classdef Kopp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure          matlab.ui.Figure
        ChoosefolderButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        
        % general properties (static)
        Name = 'Kopp'; % app name
        Version = '1.1.0'; % app version
        CoffeeVersion = '2.0.0-alpha.7'; % coffee version app was built with, from CFF_coffee_version()
        
        % files and folders, set in startup function
        RootFolder = ''; % root folder of app
        UserFolder = ''; % user folder for app
        ConfigFile = ''; % app config file (json format)
        DiaryFile = ''; % diary filename
        
    end
    
    
    methods (Access = private)
        
        % Initialize app's config file
        function init_config_file(app)
            if ~isfolder(fileparts(app.ConfigFile))
                % create folder
                mkdir(fileparts(app.ConfigFile));
            end
            if isfile(app.ConfigFile)
                % delete existing file
                delete(app.ConfigFile);
            end
            % initialize blank config file
            fid = fopen(app.ConfigFile,'w');
            fclose(fid);
        end
        
        % Get a field value from app's config file
        function out = get_config_field(app,fieldName)
            % init fail output
            out = '';
            if isfile(app.ConfigFile)
                % read config file
                configJSON = fileread(app.ConfigFile);
                if ~isempty(configJSON)
                    % decode config file
                    config = jsondecode(configJSON);
                    if isfield(config,fieldName)
                        % read field
                        out = config.(fieldName);
                    end
                end
            end
        end
        
        % Set a field value in app's config file
        function out = set_config_field(app,fieldName,fieldVal)
            % init fail output
            out = false;
            if isfile(app.ConfigFile)
                % read config file
                configJSON = fileread(app.ConfigFile);
                if ~isempty(configJSON)
                    % decode config file
                    config = jsondecode(fileread(app.ConfigFile));
                else
                    % inititate blank config
                    config = struct();
                end
                % set value
                config.(fieldName) = fieldVal;
                % encode config
                configJSON = jsonencode(config);
                % rewrite config file
                fid = fopen(app.ConfigFile,'w');
                fprintf(fid,'%c',configJSON);
                fclose(fid);
                % success
                out = true;
            end
        end
        
        % Check if a folder contains the CoFFee toolbox
        function isCoffeeFolder = is_coffee_folder(~,folder)
            % for now, we just test the existence of a folder and of the
            % coffee version function
            if isfolder(folder) && exist(fullfile(folder,'CFF_coffee_version.m'),'file')
                isCoffeeFolder = true;
            else
                isCoffeeFolder = false;
            end
        end
        
        % Get the CoFFee version from its folder
        function coffeeVer = get_coffee_version(app,folder)
            % first, check that it is a coffee folder
            if ~is_coffee_folder(app,folder)
                coffeeVer = NaN;
                return
            end
            % get version of that coffee
            curdir = cd;
            cd(folder);
            coffeeVer = CFF_coffee_version();
            cd(curdir);
        end
        
        % Check if a CoFFee folder has the expected version
        function isVersionOK = is_coffee_version(app,folder,coffeeVerWanted)
            % first, check that it is a coffee folder
            if ~is_coffee_folder(app,folder)
                isVersionOK = NaN;
                return
            end
            % next, get version of that coffee
            coffeeVerActual = get_coffee_version(app,folder);
            % finally, compare to expected version
            if strcmp(coffeeVerActual,coffeeVerWanted)
                isVersionOK = true;
            else
                isVersionOK = false;
            end
        end
        
        %%%%
        function mainRun(app, folder)
            
            % initialize runtime params struct
            runtimeParams = struct('file','time','params',[]);
            dd = 0;
            
            % list all files in folder
            rawDataFilesList = CFF_list_raw_files_in_dir(folder,1);
            nFiles = numel(rawDataFilesList);
           
            fprintf('\nAnalysing contents of folder %s (recursively)...\n',folder);
            fprintf('Found %i files.\n',nFiles);
            
            fprintf('\nREADING FILES:\n')
            
            % progress bar
            d = uiprogressdlg(app.MainFigure,'Title','Please Wait',...
                'Message','Reading Runtime Parameters in files',...
                'Cancelable','on');
            
            % for each file
            for ii = 1:nFiles
                
                % check for cancel request
                if d.CancelRequested
                    close(d);
                    fprintf('Analysis cancelled\n',nFiles,folder);
                    return
                end
                
                % update progress bar
                d.Message = sprintf('Reading file %i/%i',ii,nFiles);
                d.Value = ii./(nFiles+1);
                
                % get file name
                rawFile = rawDataFilesList{ii};
                if iscell(rawFile)
                    % pair of .all/.wcd
                    rawFile = CFF_onerawfileonly(rawFile);
                    rawFile = rawFile{1};
                end
                
                % parse file for runtime parameters
                ALLdata = CFF_read_all(rawFile,82);
                
                % decode runtime parameters
                runtimeData = CFF_decode_RuntimeParameters(ALLdata.EM_Runtime);
                runtimeFields = fieldnames(runtimeData);
                nDatags = numel(ALLdata.EM_Runtime.PingCounter);
                
                fprintf('File %i/%i (%s): Found %i runtime parameters datagrams.\n',ii,nFiles,rawFile,nDatags);
                
                % for each datagram
                for jj = 1:nDatags
                    
                    % increase counter for saving records
                    dd = dd+1;
                    
                    % add filename and datagram time to the struct
                    runtimeParams(dd).file = ALLdata.ALLfilename;
                    runtimeParams(dd).time = app.datetimeFromKongsberg(...
                        ALLdata.EM_Runtime.Date(jj),...
                        ALLdata.EM_Runtime.TimeSinceMidnightInMilliseconds(jj));
                    
                    % then add each parameter, as a string
                    for kk = 1:numel(runtimeFields)
                        par = runtimeFields{kk};
                        val = runtimeData.(par)(jj);
                        if isnumeric(val)
                            val = num2str(val);
                        elseif iscategorical(val)
                            val = char(val);
                        end
                        runtimeParams(dd).params.(par) = val;
                    end
                end
                
            end
            
            d.Message = sprintf('Compiling results');
            
            % sort records chronologically (in case file names were not
            % accurately named by time)
            [~,indf] = sort([runtimeParams.time]','ascend');
            runtimeParams = runtimeParams(indf);
            nDatags = numel(runtimeParams);
            
            fprintf('\nCHRONOLOGICAL CHANGES:\n')
            
            % disp all params from first datagram in first file
            fprintf('%s first file started (%s)\n',datestr(runtimeParams(1).time,'dd-mmm-yyyy HH:MM:SS.FFF'),runtimeParams(1).file);
            fprintf('   Initial parameters:\n')
            fields = fieldnames(runtimeParams(1).params);
            for kk = 1:numel(fields)
                fprintf('   * %s: %s\n',fields{kk}, runtimeParams(1).params.(fields{kk}))
            end
            
            % then go through each record
            for ii = 2:nDatags
                % check if new file
                if ~strcmp(runtimeParams(ii).file,runtimeParams(ii-1).file)
                    fprintf('%s new file started (%s)\n',datestr(runtimeParams(ii).time,'dd-mmm-yyyy HH:MM:SS.FFF'),runtimeParams(ii).file);
                end
                
                % check parameters compared to previous datagram
                fields = fieldnames(runtimeParams(ii).params);
                for kk = 1:numel(fields)
                    flag = 0;
                    doesFieldPreviouslyExist = isfield(runtimeParams(ii-1).params,fields{kk});
                    if doesFieldPreviouslyExist
                        isCurValIdentical = strcmp(runtimeParams(ii-1).params.(fields{kk}),runtimeParams(ii).params.(fields{kk}));
                        if isCurValIdentical
                            % field value identical to previous datagram. Do
                            % nothing
                        else
                            % field value different. Display
                            fprintf('   %s PARAMETER CHANGED. %s was ''%s'', and now ''%s''.\n',datestr(runtimeParams(ii).time,'dd-mmm-yyyy HH:MM:SS.FFF'),fields{kk},runtimeParams(ii-1).params.(fields{kk}),runtimeParams(ii).params.(fields{kk}));
                        end
                    else
                        % change of fields should not happen. leave blank
                        % for now but add an error later.
                    end
                    
                end

            end
            
            % at the end, produce summary results:
            fprintf('\nSUMMARY RESULTS:\n')
            for kk = 1:numel(fields)
                % find unique values for this field across all runtime
                % parameters datagram
                allValuesForThisField = arrayfun(@(s) s.params.(fields{kk}),runtimeParams,'UniformOutput',false);
                uniqueValuesForThisField = unique(allValuesForThisField);
                
                if numel(uniqueValuesForThisField) == 1
                    
                    fprintf(' * constant %s: %s\n',fields{kk}, uniqueValuesForThisField{1});
                else
                    listOfValues = strjoin(uniqueValuesForThisField,', ');
                    fprintf(' * VARIABLE %s: %s\n',fields{kk}, listOfValues);
                end
            end
            
            d.Message = sprintf('Done');
            close(d);
            
        end
        
        % return datetime from date and time in Kongsberg format (date and
        % time since midnight in milliseconds)
        function out = datetimeFromKongsberg(~,D,TSMIM)
            SDN = datenum(num2str(D),'yyyymmdd') + TSMIM/(24*60*60*1000);
            out = datetime(SDN,'ConvertFrom','datenum');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        
            %% 1. Initialization
            
            % Start time
            startTime = now;
            
            % Set (and create if needed) the app user folder
            app.UserFolder = regexprep(userpath,'MATLAB',app.Name);
            if ~isfolder(app.UserFolder)
                mkdir(app.UserFolder);
            end
            
            % Start diary
            diary('off');
            app.DiaryFile = fullfile(app.UserFolder, sprintf('%s_%s.log',datestr(startTime,'yyyymmddTHHMMSS'),app.Name));
            diary(app.DiaryFile);
            diary('on');
            
            % Start messages
            fprintf('%s - Starting %s v%s (powered by CoFFee v%s). Please wait...\n',datestr(startTime),app.Name,app.Version,app.CoffeeVersion);
            fprintf('Find a log of this output at %s.\n',app.DiaryFile)
            
            %% 2. System files and folders
            
            % If on MATLAB, add the app's root folder (and its subfolders)
            % to the path to access its files
            app.RootFolder = fileparts(mfilename('fullpath'));
            if ~isdeployed
                addpath(genpath(app.RootFolder));
            end
            
            % Init config file if needed
            app.ConfigFile = fullfile(app.UserFolder,'config.json');
            if ~isfile(app.ConfigFile)
                init_config_file(app);
            end
            
            % If on MATLAB, need to find and add CoFFee to the path
            if ~isdeployed
                % check coffee folder
                coffeeFolder = get_config_field(app,'coffeeFolder');
                if ~is_coffee_folder(app,coffeeFolder)
                    % throw an alert to find manually a suitable coffee folder
                    coffeeFolder = uigetdir(app.RootFolder,'Select suitable CoFFee folder');
                    figure(app.MainFigure); % uigetdir sometimes puts app in the background. Bring it back on top
                    % second check
                    if ~is_coffee_folder(app,coffeeFolder)
                        error('Not a CoFFee folder.');
                    end
                end
                % check coffee version is the one we need
                isVersionOK = is_coffee_version(app,coffeeFolder,app.CoffeeVersion);
                if ~isVersionOK
                    warning(['This version of %s (%s) was built with a version'...
                        ' of CoFFee (%s) that is different from that (%s) of the toolbox'...
                        ' you have specified (%s). Proceeding, but you may experience'...
                        ' issues. Consider checking out the correct CoFFee version.'],...
                        app.Name,app.Version,app.CoffeeVersion,get_coffee_version(app,coffeeFolder),coffeeFolder);
                end
                % add coffee to path
                addpath(genpath(coffeeFolder));
                % save that path in config file
                set_config_field(app,'coffeeFolder',coffeeFolder);
            end
            
            %% 3. Visual setup
            
            % Main figure icon and name
            app.MainFigure.Icon = 'figure_icon.png';
            app.MainFigure.Name = sprintf('%s v%s',app.Name,app.Version);
            
            
            %% 4. Finalize start-up
            fprintf('Ready.\n');
            
        end

        % Button pushed function: ChoosefolderButton
        function ChoosefolderButtonPushed(app, event)
            
            % Open folder selection dialog box
            newFolder = uigetdir(pwd,'Select folder of raw data files');
            figure(app.MainFigure); % uigetdir sometimes puts app in the background. Bring it back on top
            if newFolder ~= 0
                % new run
                app.mainRun(newFolder);
            end
        end

        % Close request function: MainFigure
        function MainFigureCloseRequest(app, event)
            
            % last messages and diary off
            fprintf('Closing %s...\n',app.Name);
            fprintf('Find a log of this output at %s.\n',app.DiaryFile)
            fprintf('Bye.\n');
            diary('off');
            
            % delete the app
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'MATLAB App';
            app.MainFigure.CloseRequestFcn = createCallbackFcn(app, @MainFigureCloseRequest, true);
            app.ChoosefolderButton = uibutton(app.MainFigure, 'push');
            app.ChoosefolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefolderButtonPushed, true);
            app.ChoosefolderButton.Text = 'Choose folder';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kopp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end